classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        OrderSlider                     matlab.ui.control.Slider
        Slider_2Label                   matlab.ui.control.Label
        AutomaticfilterparametersLabel  matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        FrequencyAnalysisLabel          matlab.ui.control.Label
        ProcessingTypeLabel             matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        MovingRMSButton                 matlab.ui.control.RadioButton
        BandpassfilterrectificationButton  matlab.ui.control.RadioButton
        Tree2                           matlab.ui.container.Tree
        MTrapDescendensLNode_2          matlab.ui.container.TreeNode
        MTrapDescendensRNode_2          matlab.ui.container.TreeNode
        MDeltodeusAnteriorLNode_2       matlab.ui.container.TreeNode
        MDeltodeusAnteriorRNode_2       matlab.ui.container.TreeNode
        MExtCarpiRadialisdomNode_2      matlab.ui.container.TreeNode
        lampCH5                         matlab.ui.control.Lamp
        lampCH4                         matlab.ui.control.Lamp
        lampCH3                         matlab.ui.control.Lamp
        lampCH2                         matlab.ui.control.Lamp
        lampCH1                         matlab.ui.control.Lamp
        Image_2                         matlab.ui.control.Image
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        OutlierremovalLabel             matlab.ui.control.Label
        ExportdataButton                matlab.ui.control.Button
        LoaddataButton                  matlab.ui.control.Button
        DataManagementLabel             matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        InfoButton                      matlab.ui.control.Button
        ApplySavitzkyGolayCheckBox      matlab.ui.control.CheckBox
        AddtoexportCheckBox             matlab.ui.control.CheckBox
        ShowongraphCheckBox             matlab.ui.control.CheckBox
        MVCLabel                        matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        RMSwindowsizeLabel              matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.Button
        OrderEditField                  matlab.ui.control.NumericEditField
        OrderEditFieldLabel             matlab.ui.control.Label
        CutoffEditField                 matlab.ui.control.NumericEditField
        CutoffEditFieldLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        FilterDropDown                  matlab.ui.control.DropDown
        FilterDropDownLabel             matlab.ui.control.Label
        BandPassFilterLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        dataCh1
        dataCh2
        dataCh3
        dataCh4
        dataCh5

        mvcCh1
        mvcCh2
        mvcCh3
        mvcCh4
        mvcCh5


    end
    
    methods (Access = private)
        
        function PlotRms(app, window)
           movrmsWin = dsp.MovingRMS(window);
            y1 = movrmsWin(app.rawdata);
            plot(app.UIAxes_2, y1(20:end)) 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;
            
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
               disp('User selected Cancel');
            else
                disp(fullfile(path,file))
               [data, t, header] = FileParser(fullfile(path,file));

               app.dataCh1 = data(:,1);
               app.dataCh2 = data(:,2);
               app.dataCh3 = data(:,3);
               app.dataCh4 = data(:,4);
               app.dataCh5 = data(:,5);

               uiconfirm(app.UIFigure,'File has been loaded succesfully! Please select a channel.','File loaded',...
                                    'Icon','info');

               disp(['User selected ', fullfile(path,file)]);
               
               selection = uiconfirm(app.UIFigure,'Please select MVC for M. Trap. Descendens (L)!','MVC M. Trap. Descendens (L)',...
                                    'Icon','info');
                % Handle response
                switch selection
                    case 'OK'
                        [file,path] = uigetfile('*.txt');
                        if isequal(file,0)
                           disp('User selected Cancel');
                        else
                            app.lampCH1.Color = [0 1 0];
                            % Plot moving RMS
                            app.rawdata = data(:,3);

                            PlotRms(app, round(app.Slider.Value));


                        end
                    case 'Cancel'
                        disp('...')
                end

            end
        end

        % Button pushed function: ExportdataButton
        function ExportdataButtonPushed(app, event)
            uigetdir(matlabroot,'Choose folder to save the analysis');
            app.Label_2.Visible = 'on';
        end

        % Callback function
        function LoadMVCButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
               app.lampCH1.Color = [0 1 0];
            end   
        end

        % Callback function
        function CleardataButtonPushed(app, event)

            selection = uiconfirm(app.UIFigure,'Clear data?','Confirm Clearing',...
                                    'Icon','warning');
            % Handle response
            switch selection
                case 'OK'
                    disp('Clearing...')
                    app.lampCH1.Color = [1 0 0];
                    app.Lamp.Color = [1 0 0];
                case 'Cancel'
                    disp('...')
            end
        end

        % Callback function
        function TreeCheckedNodesChanged(app, event)

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            disp(round(value))
            PlotRms(app, round(value));
        end

        % Selection changed function: Tree2
        function Tree2SelectionChanged(app, event)
            selectedNodes = app.Tree2.SelectedNodes;
            if (selectedNodes.Text == "M. Trap. Descendens (L)")
                if (app.mvcCh1 ~= 0)
                    disp(" Not Empty")
                else
                    disp("Empty")
                    selection = uiconfirm(app.UIFigure,'Please select MVC for M. Trap. Descendens (L)!','MVC M. Trap. Descendens (L)',...
                                    'Icon','info');
                    switch selection
                        case 'OK'
                            [file,path] = uigetfile('*.txt');
                            if isequal(file,0)
                               disp('User selected Cancel');
                            else
                                output = dataProcessing(fullfile(path,file), round(app.OrderSlider.Value), false, true, 1, 1, 0.2, 0.5)
                                app.mvcCh1 = output(1);
                                app.lampCH1.Color = [0 1 0];
                            end
                    end
                end

        
            elseif (selectedNodes.Text == "M. Trap. Descendens (R)")
                if (app.mvcCh2 ~= 0)
                    disp(" Not Empty")
                else
                    disp("Empty")
                    selection = uiconfirm(app.UIFigure,'Please select MVC for M. Trap. Descendens (R)!','MVC M. Trap. Descendens (R)',...
                                    'Icon','info');
                    switch selection
                        case 'OK'
                            [file,path] = uigetfile('*.txt');
                            if isequal(file,0)
                               disp('User selected Cancel');
                            else
                                output = dataProcessing(fullfile(path,file), round(app.OrderSlider.Value), false, true, 1, 1, 200, 500)
                                app.mvcCh2 = output(2);
                                app.lampCH2.Color = [0 1 0];
                            end
                    end
                end
    
            elseif (selectedNodes.Text == "M. Deltoïdeus Anterior (L)")
                if (app.mvcCh3 ~= 0)
                    disp(" Not Empty")
                else
                    disp("Empty")
                    selection = uiconfirm(app.UIFigure,'Please select MVC for M. Deltoïdeus Anterior (L)!','MVC M. Deltoïdeus Anterior (L)',...
                                    'Icon','info');
                    switch selection
                        case 'OK'
                            [file,path] = uigetfile('*.txt');
                            if isequal(file,0)
                               disp('User selected Cancel');
                            else
                                output = dataProcessing(fullfile(path,file), round(app.OrderSlider.Value), false, true, 1, 1, 200, 500)
                                app.mvcCh3 = output(3);
                                app.lampCH3.Color = [0 1 0];
                            end
                    end
                end
    
            elseif (selectedNodes.Text == "M. Deltoïdeus Anterior (R)")
                if (app.mvcCh4 ~= 0)
                    disp(" Not Empty")
                else
                    disp("Empty")
                    selection = uiconfirm(app.UIFigure,'Please select MVC for M. Deltoïdeus Anterior (R)!','MVC M. Deltoïdeus Anterior (R)',...
                                    'Icon','info');
                    switch selection
                        case 'OK'
                            [file,path] = uigetfile('*.txt');
                            if isequal(file,0)
                               disp('User selected Cancel');
                            else
                                output = dataProcessing(fullfile(path,file), round(app.OrderSlider.Value), false, true, 1, 1, 200, 500)
                                app.mvcCh4 = output(4);
                                app.lampCH4.Color = [0 1 0];
                            end
                    end
                end
    
            elseif (selectedNodes.Text == "M. Ext. Carpi Radialis (dom.)")
                if (app.mvcCh1 ~= 0)
                    disp(" Not Empty")
                else
                    disp("Empty")
                    selection = uiconfirm(app.UIFigure,'Please select MVC for M. Ext. Carpi Radialis (dom.)!','MVC M. Ext. Carpi Radialis (dom.)',...
                                    'Icon','info');
                    switch selection
                        case 'OK'
                            [file,path] = uigetfile('*.txt');
                            if isequal(file,0)
                               disp('User selected Cancel');
                            else
                                output = dataProcessing(fullfile(path,file), round(app.OrderSlider.Value), false, true, 1, 1, 200, 500)
                                app.mvcCh5 = output(5);
                                app.lampCH5.Color = [0 1 0];
                            end
                    end
                end
            else
                disp("idk")
            end
        end

        % Value changed function: OrderSlider
        function OrderSliderValueChanged(app, event)
            value = app.OrderSlider.Value;
            app.OrderSlider.Value = round(value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {629, 629, 629};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {629, 629};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {236, '1x', 230};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataManagementLabel = uilabel(app.LeftPanel);
            app.DataManagementLabel.Text = 'Data Management';
            app.LoaddataButton = uibutton(app.LeftPanel, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.ExportdataButton = uibutton(app.LeftPanel, 'push');
            app.ExportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdataButtonPushed, true);
            app.ExportdataButton.Text = 'Export data';
            app.OutlierremovalLabel = uilabel(app.LeftPanel);
            app.OutlierremovalLabel.Text = 'Outlier removal';
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.Value = 'On';
            app.MethodDropDownLabel = uilabel(app.LeftPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.LeftPanel);
            app.MethodDropDown.Items = {'STD', 'IQR', 'Z-Score', 'Percentile', 'Winsorization'};
            app.MethodDropDown.Value = 'STD';
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.ImageSource = 'logo.png';
            app.lampCH1 = uilamp(app.LeftPanel);
            app.lampCH2 = uilamp(app.LeftPanel);
            app.lampCH3 = uilamp(app.LeftPanel);
            app.lampCH4 = uilamp(app.LeftPanel);
            app.lampCH5 = uilamp(app.LeftPanel);
            app.Tree2 = uitree(app.LeftPanel);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @Tree2SelectionChanged, true);
            app.MTrapDescendensLNode_2 = uitreenode(app.Tree2);
            app.MTrapDescendensLNode_2.Text = 'M. Trap. Descendens (L)';
            app.MTrapDescendensRNode_2 = uitreenode(app.Tree2);
            app.MTrapDescendensRNode_2.Text = 'M. Trap. Descendens (R)';
            app.MDeltodeusAnteriorLNode_2 = uitreenode(app.Tree2);
            app.MDeltodeusAnteriorLNode_2.Text = 'M. Deltoïdeus Anterior (L)';
            app.MDeltodeusAnteriorRNode_2 = uitreenode(app.Tree2);
            app.MDeltodeusAnteriorRNode_2.Text = 'M. Deltoïdeus Anterior (R)';
            app.MExtCarpiRadialisdomNode_2 = uitreenode(app.Tree2);
            app.MExtCarpiRadialisdomNode_2.Text = 'M. Ext. Carpi Radialis (dom.)';
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.BandpassfilterrectificationButton = uiradiobutton(app.ButtonGroup);
            app.BandpassfilterrectificationButton.Text = 'Band pass filter + rectification';
            app.BandpassfilterrectificationButton.Value = true;
            app.MovingRMSButton = uiradiobutton(app.ButtonGroup);
            app.MovingRMSButton.Text = 'Moving RMS';
            app.ProcessingTypeLabel = uilabel(app.LeftPanel);
            app.ProcessingTypeLabel.Text = 'Processing Type';
            app.FrequencyAnalysisLabel = uilabel(app.LeftPanel);
            app.FrequencyAnalysisLabel.Text = 'Frequency Analysis';
            app.Switch_2 = uiswitch(app.LeftPanel, 'slider');
            app.Switch_2.Value = 'On';
            app.AutomaticfilterparametersLabel = uilabel(app.LeftPanel);
            app.AutomaticfilterparametersLabel.Text = {'Automatic '; 'filter parameters'};
            app.Slider_2Label = uilabel(app.LeftPanel);
            app.Slider_2Label.Text = 'Slider';
            app.OrderSlider = uislider(app.LeftPanel);
            app.OrderSlider.Limits = [1 3];
            app.OrderSlider.MajorTicks = [1 2 3];
            app.OrderSlider.ValueChangedFcn = createCallbackFcn(app, @OrderSliderValueChanged, true);
            app.OrderSlider.MinorTicks = [1 2 3];
            app.OrderSlider.Value = 1;
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Rectification with Band-pass')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.BandPassFilterLabel = uilabel(app.RightPanel);
            app.BandPassFilterLabel.Text = 'Band Pass Filter';
            app.FilterDropDownLabel = uilabel(app.RightPanel);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.RightPanel);
            app.FilterDropDown.Items = {'Band-pass', 'Low-pass', 'High-pass', 'Notch', 'Savitzky-Golay'};
            app.FilterDropDown.Value = 'Band-pass';
            app.Image = uiimage(app.RightPanel);
            app.CutoffEditFieldLabel = uilabel(app.RightPanel);
            app.CutoffEditFieldLabel.Text = 'Cutoff';
            app.CutoffEditField = uieditfield(app.RightPanel, 'numeric');
            app.OrderEditFieldLabel = uilabel(app.RightPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.RightPanel, 'numeric');
            app.DefaultButton = uibutton(app.RightPanel, 'push');
            app.DefaultButton.Text = 'Default';
            app.RMSwindowsizeLabel = uilabel(app.RightPanel);
            app.RMSwindowsizeLabel.Text = 'RMS window size';
            app.Slider = uislider(app.RightPanel);
            app.Slider.Limits = [1 30];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            app.MVCLabel = uilabel(app.RightPanel);
            app.MVCLabel.Text = 'MVC';
            app.ShowongraphCheckBox = uicheckbox(app.RightPanel);
            app.ShowongraphCheckBox.Text = 'Show on graph';
            app.AddtoexportCheckBox = uicheckbox(app.RightPanel);
            app.AddtoexportCheckBox.Text = 'Add to export';
            app.ApplySavitzkyGolayCheckBox = uicheckbox(app.RightPanel);
            app.ApplySavitzkyGolayCheckBox.Text = 'Apply Savitzky-Golay';
            app.InfoButton = uibutton(app.RightPanel, 'push');
            app.InfoButton.Text = 'Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end